classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LabellingPanel           matlab.ui.container.Panel
        recentStepLabel          matlab.ui.control.Label
        lastStepTime             matlab.ui.control.Label
        LasttimeLabel            matlab.ui.control.Label
        MostrecentlabelLabel     matlab.ui.control.Label
        EndButton                matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        AligntoStepStartButton   matlab.ui.control.Button
        AligntoStepEndButton     matlab.ui.control.Button
        SaveLabelsButton         matlab.ui.control.Button
        LabelStartEndButton      matlab.ui.control.Button
        LabelStepBoundaryButton  matlab.ui.control.Button
        ExtractStepButton        matlab.ui.control.Button
        video_file_name_label    matlab.ui.control.Label
        SelectVideoButton        matlab.ui.control.Button
        FileNameLabel_2          matlab.ui.control.Label
        VideoFileLabel           matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        FileNameLabel            matlab.ui.control.Label
        DataFileLabel            matlab.ui.control.Label
        SelectDataButton         matlab.ui.control.Button
        Slider_2                 matlab.ui.control.Slider
        Slider                   matlab.ui.control.Slider
        PlayButton               matlab.ui.control.Button
        LoadDataButton           matlab.ui.control.Button
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AllFrames % Struct array to store all video frames
        noOfFrames % Number of frames in the video
        dataLine % Vertical line indicating current frame in acceleration plot
        selectedDateFilePath % Path of selected data file
        selectedVideoFilePath % Path of selected video file
        absoluteSelectedDataFileDir % Absolute directory of selected data file
        endDataLine % Vertical line indicating end frame in acceleration plot
        acc_mag_left % Filtered acceleration magnitude for left foot
        acc_mag_right % Filtered acceleration magnitude for right foot
        ylimMin % Minimum y-axis limit for acceleration plot
        ylimMax % Maximum y-axis limit for acceleration plot
        steps = [] % Collection of extracted steps
        stepCounter = 0 % Counter for steps
        stepBoundaryStartIndex = -1 % Index of step boundary start
        stepBoundaryEndIndex = -1 % Index of step boundary end
        stepStartBoundaryIndex = [] % Start and end index for step start
        stepEndBoundaryIndex = [] % Start and end index for step end
        stepBoundaryStartSliderValue % Slider value for step boundary start
        stepBoundaryEndSliderValue % Slider value for step boundary end
        accelerationXLeft % Raw acceleration data for left foot in X direction
        accelerationYLeft % Raw acceleration data for left foot in Y direction
        accelerationZLeft % Raw acceleration data for left foot in Z direction
        accelerationXRight % Raw acceleration data for right foot in X direction
        accelerationYRight % Raw acceleration data for right foot in Y direction
        accelerationZRight % Raw acceleration data for right foot in Z direction
        fileName % Selected data file name
    end
    
    methods (Access = public)
        
        function results = updateFramesBasedOnSliderChangeButton(app,value)
            idx = floor(value);

            if idx == 0
                idx = 1;
            end
           
            imshow(app.AllFrames(idx).cdata,'Parent',app.UIAxes);
        end
        
        function results = updateLabellingHint(app,stepLabel,lastTime)
            app.recentStepLabel.Text = strcat(num2str(stepLabel),'cm');
            app.lastStepTime.Text = num2str(lastTime);
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % videoObj = VideoReader("walking-video.mp4");

            videoObj = VideoReader(app.selectedVideoFilePath);
            numFrames = videoObj.NumFrames;
            app.noOfFrames = numFrames;
            disp(app.noOfFrames)
            disp("Duration")
            disp(videoObj.Duration);
            disp("FrameRate");
            disp(videoObj.FrameRate);

            app.Slider.Limits = [0, videoObj.NumFrames];
            app.Slider_2.Limits = [0, videoObj.NumFrames];
            app.AllFrames = struct("cdata",zeros(videoObj.Height,videoObj.Width,3,"uint8"),colormap=[]);
            
            k = 1;
            while hasFrame(videoObj)
                vidFrame = readFrame(videoObj);
                app.AllFrames(k).cdata = vidFrame;
                k = k+1;
                imshow(vidFrame,'Parent',app.UIAxes);
                app.Slider.Value = k - 1;
               % app.Slider.Value = 
                % pause(1/videoObj.FrameRate);
                set(app.dataLine,'Value',videoObj.CurrentTime);
                set(app.endDataLine,'Value',videoObj.CurrentTime + 0.01);
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            disp(app.selectedDateFilePath)
            % data = readmatrix("data.csv");
            data = readmatrix(app.selectedDateFilePath);
            
            time = data(:,1);

            % Acceleration for left foot
            accXLeft = data(:,2);
            accYLeft = data(:,3);
            accZLeft = data(:,4);

            % Acceleration for right foot
            accXRight = data(:,10);
            accYRight = data(:,11);
            accZRight = data(:,12);
            
            %Update the gloabl scope
            
            app.accelerationXLeft = accXLeft;
            app.accelerationYLeft = accYLeft;
            app.accelerationZLeft = accZLeft;

            app.accelerationXRight = accXRight;
            app.accelerationYRight = accYRight;
            app.accelerationZRight = accZRight;


            % Acceleration Magnitued
            accMagLeft = data(:,5);
            accMagRight = data(:,13);
            
            % apply filtering
            
            % frequency (Hz)
            fs = 100;
            cutoff_frequency = 3;
            [b,a] = butter(3,cutoff_frequency / (fs/2),'low');

            output_signal_acc_mag_left = filter(b,a,accMagLeft);
            output_signal_acc_mag_right = filter(b,a,accMagRight);

            % update the global scope
            app.acc_mag_left = output_signal_acc_mag_left;
            app.acc_mag_right = output_signal_acc_mag_right;

            disp(size(output_signal_acc_mag_left));
          

            % set x & y scale limits
            
            xlim(app.UIAxes2,[min(time),max(time)]);
            ymax = 0;
            ymin = 0;
            
            if max(accMagLeft) < max(accMagRight)
                ymax = max(accMagRight);
            else
                ymax = max(accMagLeft);
            end

            if min(accMagLeft) < min(accMagRight)
                ymin = min(accMagLeft);
            else
                ymin = min(accMagRight);
            end

            ylim(app.UIAxes2,[ymin,ymax]);

            app.ylimMin = ymin;
            app.ylimMax = ymax;


            % plot(app.UIAxes2,time,accMagLeft,'g',time,accMagRight,'r',time,output_signal_acc_mag_left,'c', time,output_signal_acc_mag_right,'k');

             plot(app.UIAxes2,time,output_signal_acc_mag_left,'c', time,output_signal_acc_mag_right,'k');


            % set legend
            legend(app.UIAxes2,{'filteredAccMagLeft','filteredAccelerationMagRight'},'FontSize',16,'AutoUpdate','off');
            
            % set plot slider
            %app.Slider2.Limits = [0,time(end)];

            % set lineHandle for data
            app.dataLine = xline(app.UIAxes2,0,'b','LineWidth',2);
            app.endDataLine = xline(app.UIAxes2,0,'r','LineWidth',2);

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            % idx = round((changingValue - 1));
            idx = floor(changingValue);

            if idx == 0
                idx = 1;
            end
           
            imshow(app.AllFrames(idx).cdata,'Parent',app.UIAxes);

            % update the dataLine pos
            time = changingValue / 30.0113;
            set(app.dataLine,'Value',time);
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');

            app.absoluteSelectedDataFileDir = path;
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                % Update the UI with selected file name
                [filepath,name,ext] = fileparts(file);
            
                fileName = sprintf('%s', name);
     
                app.Label.Text = fileName;
                app.fileName = fileName;

                % filepath = sprintf('%s', filepath);
                filepath = convertCharsToStrings(fullfile(path,file));
                
                app.selectedDateFilePath = filepath;
                drawnow;
            end
        end

        % Button pushed function: SelectVideoButton
        function SelectVideoButtonPushed(app, event)
             [file,path] = uigetfile('*.*');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                % Update the UI with selected file name
                [filepath,name,ext] = fileparts(file);
                fileName = sprintf('%s', name);
                app.video_file_name_label.Text = fileName;

                % filepath = sprintf('%s', filepath);
                filepath = convertCharsToStrings(fullfile(path,file));
                
                app.selectedVideoFilePath = filepath;
                drawnow;
            end
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            % idx = round((changingValue - 1));
            idx = floor(changingValue);

            if idx == 0
                idx = 1;
            end
           
            
            imshow(app.AllFrames(idx).cdata,'Parent',app.UIAxes);

            % update the dataLine pos
            time = changingValue / 30.0113;
            set(app.endDataLine,'Value',time);
            
        end

        % Button pushed function: ExtractStepButton
        function ExtractStepButtonPushed(app, event)
            % startDateLineTime = app.Slider.Value / 30.0113;
            % endDataLineTime = app.Slider_2.Value /  30.0113;

            % startIdx = startDateLineTime * 100;
            % endIdx = endDataLineTime * 100;

            % Ask Step Length from user
            prompt = "Step Length ?";
            dlgtitle = "Step Length";
            fieldsize = [1 45];
            stepLengthInCM = inputdlg(prompt,dlgtitle,fieldsize);
            stepLengthInCM = stepLengthInCM{1};
            stepLengthInCM = str2double(stepLengthInCM);
            

            % Draw a vertical bar from start to end index to indicate the
            % marked samples for start and end of a start or end of a step.

            app.steps(app.stepCounter+1).accelerationMagnitudeLeft = app.acc_mag_left(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex);
            app.steps(app.stepCounter+1).accelerationMagnitudeRight = app.acc_mag_right(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex);
            

            % Add raw accelerations for Left & Right
            %Left
            app.steps(app.stepCounter+1).rawAccelerationXLeft = app.accelerationXLeft(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex);
            app.steps(app.stepCounter+1).rawAccelerationYLeft = app.accelerationYLeft(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex);
            app.steps(app.stepCounter+1).rawAccelerationZLeft = app.accelerationZLeft(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex);

            % Right
            app.steps(app.stepCounter+1).rawAccelerationXRight = app.accelerationXRight(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex);
            app.steps(app.stepCounter+1).rawAccelerationYRight = app.accelerationYRight(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex);
            app.steps(app.stepCounter+1).rawAccelerationZRight = app.accelerationZRight(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex);
            

            app.steps(app.stepCounter+1).stepLengthInCM = stepLengthInCM;
            app.steps(app.stepCounter+1).stepStartIndex = int64(floor(abs(app.stepStartBoundaryIndex(1) - app.stepBoundaryStartIndex)));
            app.steps(app.stepCounter+1).stepStartEndIndex = int64(floor(abs(app.stepStartBoundaryIndex(2) - app.stepBoundaryStartIndex)));
            app.steps(app.stepCounter+1).stepEndStartIndex = int64(floor(abs(app.stepStartBoundaryIndex(3) - app.stepBoundaryStartIndex)));
            stepEndEndIdx = size(app.acc_mag_left(app.stepBoundaryStartIndex:app.stepBoundaryEndIndex));
            app.steps(app.stepCounter+1).stepEndEndIndex = int64(stepEndEndIdx(1));

            % add step times
            app.steps(app.stepCounter+1).startTimeSecond = app.stepBoundaryStartIndex / 100;
            app.steps(app.stepCounter+1).endTimeSecond = app.stepBoundaryEndIndex / 100;
            

            % app.step = null;
            lastTime = round((app.stepBoundaryEndIndex / 100),2);

           
            disp("Displaying steps array");
            disp(app.steps(app.stepCounter+1));

            app.stepCounter = app.stepCounter + 1;
            
            % Clear class property for next step label
            app.stepBoundaryStartIndex = -1;
            app.stepBoundaryEndIndex = -1;
            app.stepStartBoundaryIndex = [];

            app.stepBoundaryStartSliderValue = 0;
            app.stepBoundaryEndSliderValue = 0;

            % Update labelling hint
            updateLabellingHint(app,stepLengthInCM,lastTime);


        end

        % Button pushed function: LabelStepBoundaryButton
        function LabelStepBoundaryButtonPushed(app, event)

            app.stepBoundaryStartSliderValue = app.Slider.Value;
            app.stepBoundaryEndSliderValue = app.Slider_2.Value;

            startDateLineTime = app.Slider.Value / 30.0113;
            endDataLineTime = app.Slider_2.Value /  30.0113;
            
            % Update the class property for furthur use
            app.stepBoundaryStartIndex = (startDateLineTime * 100);
            app.stepBoundaryEndIndex = (endDataLineTime * 100);

            xregion(app.UIAxes2,startDateLineTime,endDataLineTime,'FaceColor','y');

        end

        % Button pushed function: LabelStartEndButton
        function LabelStartEndButtonPushed(app, event)

            startDateLineTime = app.Slider.Value / 30.0113;
            endDataLineTime = app.Slider_2.Value /  30.0113;

            startIdx = startDateLineTime * 100;
            endIdx = endDataLineTime * 100;

            % Update 
            app.stepStartBoundaryIndex(end+1) = round(startIdx); 
            app.stepStartBoundaryIndex(end+1) = round(endIdx);

            disp(startIdx);
            disp(endIdx);
            
            % Paint the ROI
            xregion(app.UIAxes2,startDateLineTime,endDataLineTime,'FaceColor','m');
        end

        % Button pushed function: SaveLabelsButton
        function SaveLabelsButtonPushed(app, event)
            % Write a file with annotated steps

            % Show loading progress bar
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Saving Labels','Message','Creating file');
            pause(.3);
            
            d.Value = .6;
            d.Message = 'Saving Labels';
            
            d.Value = 1;
            d.Message = 'Finished';
            pause(1);

            close(d);
            close(fig);

            labelledFile = strcat(app.absoluteSelectedDataFileDir,'/');
            labelledFile = strcat(labelledFile,app.fileName);

            writestruct(app.steps,strcat(labelledFile,'.json'),'PrettyPrint',true);

            % Here we empty the list.
            app.steps = [];
        end

        % Button pushed function: AligntoStepStartButton
        function AligntoStepStartButtonPushed(app, event)
            app.Slider.Value = app.stepBoundaryStartSliderValue;
            set(app.dataLine,'Value',app.Slider.Value / 30.0113);
            disp(app.Slider.Value);
            updateFramesBasedOnSliderChangeButton(app,app.Slider.Value);
        end

        % Button pushed function: AligntoStepEndButton
        function AligntoStepEndButtonPushed(app, event)
            app.Slider_2.Value = app.stepBoundaryEndSliderValue;
            set(app.endDataLine,'Value',app.Slider_2.Value / 30.0113);
            updateFramesBasedOnSliderChangeButton(app,app.Slider_2.Value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Slider.Value = app.Slider.Value + 1;
            set(app.dataLine,'Value',app.Slider.Value / 30.0113);

            % Update the frame
            updateFramesBasedOnSliderChangeButton(app,app.Slider.Value);
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            app.Slider_2.Value = app.Slider_2.Value +1;
            set(app.endDataLine,'Value',app.Slider_2.Value /30.0113);

            % Update the frame
            updateFramesBasedOnSliderChangeButton(app,app.Slider_2.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Walking')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Foot mounted IMU')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, {'Acceleration magnitude '; ' '; '(m/s^2)'})
            zlabel(app.UIAxes2, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.SelectDataButton = uibutton(app.UIFigure, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.DataFileLabel = uilabel(app.UIFigure);
            app.DataFileLabel.Text = 'Data File';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name : ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.VideoFileLabel = uilabel(app.UIFigure);
            app.VideoFileLabel.Text = 'Video File';
            app.FileNameLabel_2 = uilabel(app.UIFigure);
            app.FileNameLabel_2.Text = 'File Name : ';
            app.SelectVideoButton = uibutton(app.UIFigure, 'push');
            app.SelectVideoButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoButtonPushed, true);
            app.SelectVideoButton.Text = 'Select Video';
            app.video_file_name_label = uilabel(app.UIFigure);
            app.video_file_name_label.Text = '';
            app.ExtractStepButton = uibutton(app.UIFigure, 'push');
            app.ExtractStepButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractStepButtonPushed, true);
            app.ExtractStepButton.Text = 'Extract Step';
            app.LabelStepBoundaryButton = uibutton(app.UIFigure, 'push');
            app.LabelStepBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @LabelStepBoundaryButtonPushed, true);
            app.LabelStepBoundaryButton.Text = 'Label Step Boundary';
            app.LabelStartEndButton = uibutton(app.UIFigure, 'push');
            app.LabelStartEndButton.ButtonPushedFcn = createCallbackFcn(app, @LabelStartEndButtonPushed, true);
            app.LabelStartEndButton.Text = 'Label Start / End';
            app.SaveLabelsButton = uibutton(app.UIFigure, 'push');
            app.SaveLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLabelsButtonPushed, true);
            app.SaveLabelsButton.Text = 'Save Labels';
            app.AligntoStepEndButton = uibutton(app.UIFigure, 'push');
            app.AligntoStepEndButton.ButtonPushedFcn = createCallbackFcn(app, @AligntoStepEndButtonPushed, true);
            app.AligntoStepEndButton.Text = 'Align to Step End';
            app.AligntoStepStartButton = uibutton(app.UIFigure, 'push');
            app.AligntoStepStartButton.ButtonPushedFcn = createCallbackFcn(app, @AligntoStepStartButtonPushed, true);
            app.AligntoStepStartButton.Text = 'Align to Step Start';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start++';
            app.EndButton = uibutton(app.UIFigure, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End++';
            app.LabellingPanel = uipanel(app.UIFigure);
            app.LabellingPanel.Title = 'Labelling';
            app.MostrecentlabelLabel = uilabel(app.LabellingPanel);
            app.MostrecentlabelLabel.Text = 'Most recent label';
            app.LasttimeLabel = uilabel(app.LabellingPanel);
            app.LasttimeLabel.Text = 'Last time';
            app.lastStepTime = uilabel(app.LabellingPanel);
            app.lastStepTime.Text = '';
            app.recentStepLabel = uilabel(app.LabellingPanel);
            app.recentStepLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end